classdef tictactoe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StatusLabel  matlab.ui.control.Label
        Button9      matlab.ui.control.Button
        Button8      matlab.ui.control.Button
        Button7      matlab.ui.control.Button
        Button6      matlab.ui.control.Button
        Button5      matlab.ui.control.Button
        Button4      matlab.ui.control.Button
        Button3      matlab.ui.control.Button
        Button2      matlab.ui.control.Button
        Button1      matlab.ui.control.Button
    end


    properties (Access = private)
        board = zeros(3, 3); % Game board (0 = empty, 1 = X, 2 = O)
        currentPlayer = 1; % Current player (1 = X, 2 = O) % Description
    end

    methods (Access = private)

        function initializeGame(app)
            app.board = zeros(3, 3);
            app.currentPlayer = 1;
            app.updateStatus();
            for i = 1:9
                app.(['Button', num2str(i)]).Text = '';
                app.(['Button', num2str(i)]).Enable = 'on';
            end
        end

        function updateStatus(app)
            if app.currentPlayer == 1
                app.StatusLabel.Text = 'Player X''s turn';
            else
                app.StatusLabel.Text = 'Player O''s turn';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            buttonNumber = 1; % Assuming this button is associated with button number 1
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();
                app.checkWinner();
            end


        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            buttonNumber=2;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();
                app.checkWinner();

            end

        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            buttonNumber=3;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();

            end


        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            buttonNumber=4;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();

            end

        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            buttonNumber=5;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();
            end


        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            buttonNumber=6;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();
            end

        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            buttonNumber=7;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();

            end

        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            buttonNumber=8;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();

                app.checkWinner();

            end

        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            buttonNumber=9;
            [row, col] = ind2sub([3, 3], buttonNumber);

            if app.board(row, col) == 0
                app.board(row, col) = app.currentPlayer;
                if app.currentPlayer == 1
                    app.(['Button', num2str(buttonNumber)]).Text = 'X';
                    app.currentPlayer = 2;
                else
                    app.(['Button', num2str(buttonNumber)]).Text = 'O';
                    app.currentPlayer = 1;
                end
                app.updateStatus();
                app.checkWinner();

            end
        end

        % Check for winner
        function checkWinner(app)
            winningCombinations = [1 2 3; 4 5 6; 7 8 9; 1 4 7; 2 5 8; 3 6 9; 1 5 9; 3 5 7];
            for i = 1:size(winningCombinations, 1)
                if all(app.board(winningCombinations(i, :)) == 1)
                    app.StatusLabel.Text = 'Player X wins!';
                    app.disableButtons();
                    return;
                elseif all(app.board(winningCombinations(i, :)) == 2)
                    app.StatusLabel.Text = 'Player O wins!';
                    app.disableButtons();
                    return;
                end
            end

            if all(app.board(:) ~= 0)
                app.StatusLabel.Text = 'It''s a draw!';
            end
        end


        % Disable buttons
        function disableButtons(app)
            for i = 1:9
                app.(['Button', num2str(i)]).Enable = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Player X''s turn';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tictactoe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end